classdef CodeUIStart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        RootFilenameEditField           matlab.ui.control.EditField
        RootFilenameLabel               matlab.ui.control.Label
        MATLABExportDirectoryEditField  matlab.ui.control.EditField
        MATLABExportDirectoryLabel      matlab.ui.control.Label
        IMARISExcelDirectoryEditField   matlab.ui.control.EditField
        IMARISExcelDirectoryLabel       matlab.ui.control.Label
        RunAnalysisButton               matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        ImportModesButtonGroup          matlab.ui.container.ButtonGroup
        LiveTrackButton                 matlab.ui.control.RadioButton
        LiveNonTrackPooledButton        matlab.ui.control.RadioButton
        LiveNonTrackUnpooledButton      matlab.ui.control.RadioButton
        FixedButton                     matlab.ui.control.RadioButton
        SelectImportationModesLabel     matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        Fixed2DNode                     matlab.ui.container.TreeNode
        AU565Node                       matlab.ui.container.TreeNode
        MCF10ANode                      matlab.ui.container.TreeNode
        MDA231Node                      matlab.ui.container.TreeNode
        MDA436Node                      matlab.ui.container.TreeNode
        MDA468Node                      matlab.ui.container.TreeNode
        T47DNode                        matlab.ui.container.TreeNode
        Fixed3DNode                     matlab.ui.container.TreeNode
        MCF10A3DMNode                   matlab.ui.container.TreeNode
        MDA2313DMNode                   matlab.ui.container.TreeNode
        AU5653DMNode                    matlab.ui.container.TreeNode
        T47D3DMNode                     matlab.ui.container.TreeNode
        Live2DNode                      matlab.ui.container.TreeNode
        MCFWTNode                       matlab.ui.container.TreeNode
        MDAKONode                       matlab.ui.container.TreeNode
        MDAWTNode                       matlab.ui.container.TreeNode
        T47DKONode                      matlab.ui.container.TreeNode
        T47DWTNode                      matlab.ui.container.TreeNode
        FixedRab4ANode                  matlab.ui.container.TreeNode
        NTCNode                         matlab.ui.container.TreeNode
        KD1Node                         matlab.ui.container.TreeNode
        KD4Node                         matlab.ui.container.TreeNode
        SelectDatasetCelllinesVariantsLabel  matlab.ui.control.Label
        OrganelleMachineLearningAnalysisLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            dir = uigetdir(cd);
            app.MATLABExportDirectoryEditField.Value = dir;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            dir = uigetdir(cd);
            app.IMARISExcelDirectoryEditField.Value = dir;
        end

        % Value changed function: RunAnalysisButton
        function RunAnalysisButtonValueChanged(app, event)
            %Determine celllines
            nodes = app.Tree.CheckedNodes;
            node_string = [];
            for i = 1:length(nodes)
                currentnode = nodes(i);
                if isempty(currentnode.Children)
                    node_string = [node_string, string(currentnode.Text)];
                end
            end
            
            %Detemrine import mode
            selectedmode = app.ImportModesButtonGroup.SelectedObject;
            if contains(selectedmode.Text, 'Fixed')
                mode = 'Fixed';
            elseif contains(selectedmode.Text, 'Live NonTrack')
                if contains(selectedmode.Text, 'Unpooled')
                    mode = 'Live-nonpool';
                else
                    mode = 'Live-pool';
                end
            elseif contains(selectedmode.Text, 'Live Track')
                mode = 'Track';
            end

            exp_dir = app.MATLABExportDirectoryEditField.Value;
            imp_dir = app.IMARISExcelDirectoryEditField.Value;
            root_name = app.RootFilenameEditField.Value;
            
            save('UIInputValues.mat')
            MainScript_02142023
            app.delete % Shut down the entire app
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrganelleMachineLearningAnalysisLabel = uilabel(app.UIFigure);
            app.OrganelleMachineLearningAnalysisLabel.Text = 'Organelle Machine Learning Analysis';
            app.SelectDatasetCelllinesVariantsLabel = uilabel(app.UIFigure);
            app.SelectDatasetCelllinesVariantsLabel.Text = 'Select Dataset Celllines/Variants:';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Fixed2DNode = uitreenode(app.Tree);
            app.Fixed2DNode.Text = 'Fixed 2D';
            app.AU565Node = uitreenode(app.Fixed2DNode);
            app.AU565Node.Text = 'AU565';
            app.MCF10ANode = uitreenode(app.Fixed2DNode);
            app.MCF10ANode.Text = 'MCF10A';
            app.MDA231Node = uitreenode(app.Fixed2DNode);
            app.MDA231Node.Text = 'MDA231';
            app.MDA436Node = uitreenode(app.Fixed2DNode);
            app.MDA436Node.Text = 'MDA436';
            app.MDA468Node = uitreenode(app.Fixed2DNode);
            app.MDA468Node.Text = 'MDA468';
            app.T47DNode = uitreenode(app.Fixed2DNode);
            app.T47DNode.Text = 'T47D';
            app.Fixed3DNode = uitreenode(app.Tree);
            app.Fixed3DNode.Text = 'Fixed 3D';
            app.MCF10A3DMNode = uitreenode(app.Fixed3DNode);
            app.MCF10A3DMNode.Text = 'MCF10A3DM';
            app.MDA2313DMNode = uitreenode(app.Fixed3DNode);
            app.MDA2313DMNode.Text = 'MDA2313DM';
            app.AU5653DMNode = uitreenode(app.Fixed3DNode);
            app.AU5653DMNode.Text = 'AU5653DM';
            app.T47D3DMNode = uitreenode(app.Fixed3DNode);
            app.T47D3DMNode.Text = 'T47D3DM';
            app.Live2DNode = uitreenode(app.Tree);
            app.Live2DNode.Text = 'Live 2D';
            app.MCFWTNode = uitreenode(app.Live2DNode);
            app.MCFWTNode.Text = 'MCFWT';
            app.MDAKONode = uitreenode(app.Live2DNode);
            app.MDAKONode.Text = 'MDAKO';
            app.MDAWTNode = uitreenode(app.Live2DNode);
            app.MDAWTNode.Text = 'MDAWT';
            app.T47DKONode = uitreenode(app.Live2DNode);
            app.T47DKONode.Text = 'T47DKO';
            app.T47DWTNode = uitreenode(app.Live2DNode);
            app.T47DWTNode.Text = 'T47DWT';
            app.FixedRab4ANode = uitreenode(app.Tree);
            app.FixedRab4ANode.Text = 'Fixed Rab4A';
            app.NTCNode = uitreenode(app.FixedRab4ANode);
            app.NTCNode.Text = 'NTC';
            app.KD1Node = uitreenode(app.FixedRab4ANode);
            app.KD1Node.Text = 'KD1';
            app.KD4Node = uitreenode(app.FixedRab4ANode);
            app.KD4Node.Text = 'KD4';
            app.SelectImportationModesLabel = uilabel(app.UIFigure);
            app.SelectImportationModesLabel.Text = 'Select Importation Modes:';
            app.ImportModesButtonGroup = uibuttongroup(app.UIFigure);
            app.ImportModesButtonGroup.Title = 'Import Modes';
            app.FixedButton = uiradiobutton(app.ImportModesButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.LiveNonTrackUnpooledButton = uiradiobutton(app.ImportModesButtonGroup);
            app.LiveNonTrackUnpooledButton.Text = 'Live NonTrack - Unpooled';
            app.LiveNonTrackPooledButton = uiradiobutton(app.ImportModesButtonGroup);
            app.LiveNonTrackPooledButton.Text = 'Live NonTrack - Pooled';
            app.LiveTrackButton = uiradiobutton(app.ImportModesButtonGroup);
            app.LiveTrackButton.Text = 'Live Track';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'state');
            app.RunAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.IMARISExcelDirectoryLabel = uilabel(app.UIFigure);
            app.IMARISExcelDirectoryLabel.Text = 'IMARIS Excel Directory:';
            app.IMARISExcelDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.MATLABExportDirectoryLabel = uilabel(app.UIFigure);
            app.MATLABExportDirectoryLabel.Text = 'MATLAB Export Directory:';
            app.MATLABExportDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.RootFilenameLabel = uilabel(app.UIFigure);
            app.RootFilenameLabel.Text = 'Root Filename:';
            app.RootFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CodeUIStart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end